classdef sofview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Sofview                   matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        SaveprojectMenu           matlab.ui.container.Menu
        ExportMenu                matlab.ui.container.Menu
        FlagSHypMenu              matlab.ui.container.Menu
        ViewMenu                  matlab.ui.container.Menu
        ModeMenu                  matlab.ui.container.Menu
        ZoomMenu                  matlab.ui.container.Menu
        PanMenu                   matlab.ui.container.Menu
        Rotate3DMenu              matlab.ui.container.Menu
        NoneMenu                  matlab.ui.container.Menu
        ResetMenu                 matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        DebugMenu                 matlab.ui.container.Menu
        ListBCMenu                matlab.ui.container.Menu
        ListDofsMenu              matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        NewTab                    matlab.ui.container.Tab
        GeometrytypeLabel         matlab.ui.control.Label
        GeomType                  matlab.ui.control.DropDown
        TextArea                  matlab.ui.control.TextArea
        ProblemtypeDropDownLabel  matlab.ui.control.Label
        ProblemType               matlab.ui.control.DropDown
        CreateButton              matlab.ui.control.Button
        KerneltypeDropDownLabel   matlab.ui.control.Label
        KerneltypeDropDown        matlab.ui.control.DropDown
        MaterialsTab              matlab.ui.container.Tab
        RegionLabel               matlab.ui.control.Label
        MatRegion                 matlab.ui.control.DropDown
        MaterialLabel             matlab.ui.control.Label
        MatName                   matlab.ui.control.DropDown
        MatProperties             matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        NewMaterial               matlab.ui.control.Button
        GeomTab                   matlab.ui.container.Tab
        RegionsListBoxLabel       matlab.ui.control.Label
        RegionsListBox            matlab.ui.control.ListBox
        GeomDimInfo               matlab.ui.control.Label
        MeshTab                   matlab.ui.container.Tab
        MeshdensityPanel          matlab.ui.container.Panel
        GloballcEditFieldLabel    matlab.ui.control.Label
        GloballcEditField         matlab.ui.control.NumericEditField
        TransfiniteCheckBox       matlab.ui.control.CheckBox
        XSpinnerLabel             matlab.ui.control.Label
        XtransfiniteDensity       matlab.ui.control.Spinner
        YSpinnerLabel             matlab.ui.control.Label
        YtransfiniteDensity       matlab.ui.control.Spinner
        ZSpinnerLabel             matlab.ui.control.Label
        ZtransfiniteDensity       matlab.ui.control.Spinner
        ElementshapeLabel         matlab.ui.control.Label
        ElementshapeDropDown      matlab.ui.control.DropDown
        MappingorderLabel         matlab.ui.control.Label
        MappingorderSpinner       matlab.ui.control.Spinner
        GenerateMesh              matlab.ui.control.Button
        IncompleteCheckBox        matlab.ui.control.CheckBox
        ApproximationTab          matlab.ui.container.Tab
        VariablesListBoxLabel     matlab.ui.control.Label
        VariablesListBox          matlab.ui.control.ListBox
        FEMdegreeSpinnerLabel     matlab.ui.control.Label
        FEMdegreeSpinner          matlab.ui.control.Spinner
        BoundaryConditionsTab     matlab.ui.container.Tab
        VariableDropDownLabel     matlab.ui.control.Label
        SupportVariable           matlab.ui.control.DropDown
        RegionListBoxLabel        matlab.ui.control.Label
        SupportRegions            matlab.ui.control.ListBox
        SupporttypeDropDownLabel  matlab.ui.control.Label
        SupportType               matlab.ui.control.DropDown
        ValueEditFieldLabel       matlab.ui.control.Label
        BCValue                   matlab.ui.control.EditField
        BCValueHint               matlab.ui.control.Label
        BCAdd                     matlab.ui.control.Button
        AnalysisTab               matlab.ui.container.Tab
        CalculateButton           matlab.ui.control.Button
        ResultsTab                matlab.ui.container.Tab
        Tab                       matlab.ui.container.Tab
        Tab2                      matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        Tree                      matlab.ui.container.Tree
        ModelNode                 matlab.ui.container.TreeNode
        GeometryNode              matlab.ui.container.TreeNode
        SupportsNode              matlab.ui.container.TreeNode
        LoadsNode                 matlab.ui.container.TreeNode
        StatusmessageLabel        matlab.ui.control.Label
        StatusInfo                matlab.ui.control.ListBox
        Panel                     matlab.ui.container.Panel
        NodesCheckBox             matlab.ui.control.CheckBox
        ElementsLabel             matlab.ui.control.Label
        E1D                       matlab.ui.control.CheckBox
        E2D                       matlab.ui.control.CheckBox
        Deformed                  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        model SofModel % the model managed via GUI 
        projDir % path to project directory
        projFile % name of project file
        progress mp.ProgressGUI % Progress reporter
        viewer mp.Viewer % Description
        validSupports % Description
        defbcval % Defaults for boundary condition values
    end

    methods (Access = private)
    
        function displayMessage(app, msg)
            % Display message in status area.
            app.StatusInfo.Items = [app.StatusInfo.Items,msg]; 
            scroll(app.StatusInfo,'bottom');
        end
        function status = export(app, format)
           [file,path] = uiputfile('*.dat');
           if isequal(file,0)
               app.displayMessage('Selecting file canceled');
               status = false;
               return
           end
           fpath = fullfile(path, file);
           msg = sprintf('Exporting project in FlagSHyp format in: %s', fpath);
           app.model.export(format, fpath);
           app.displayMessage(msg);
        end
        function saveProject(app)
           if app.selectProjectFile('forWrite')  
             fpath = fullfile(app.projDir, app.projFile);
             msg = sprintf('Saving project in %s', fpath);
             app.model.buildProject();
             app.model.project.writeJSON(fpath);
             app.displayMessage(msg);
           end
        end
        
        function selectGeomTemplate(app, name)
            msg = sprintf('Selecting geom: %s', name);
            app.displayMessage(msg);
        end
        
        function [status] = selectProjectFile(app, mode)
            if strcmp(mode, 'forRead');
               [file,path] = uigetfile('*.sof');
            else 
               [file, path] = uiputfile('*.sof');
            end
            if isequal(file,0)
                app.displayMessage('Selecting file canceled');
                status = false;
            else
                app.projDir = path;
                app.projFile = file;
                status = true;
            end
        end
        
        function updateGeomTab(app)
            regions = app.model.regionNames();
            dim = app.model.geometryDim();
            label = sprintf('Dimension: %d', dim);
            app.GeomDimInfo.Text = label;
            app.RegionsListBox.Items = regions;
            app.MatRegion.Items = app.model.regionNames(dim);
        end
        
        function populateProblemType(app)
            % Create items for ProblemType
            app.ProblemType.Items = mp.ProblemFactory.mainAliases();
            app.ProblemType.Value = 'Mechanical';
         end
        
        function populateGeomType(app)
           app.GeomType.Items = mp.GeomFactory.mainAliases();
           app.GeomType.Value = 'Square';
        end
        
        function [status, message] = createNewProblem(app)
            geom = app.GeomType.Value;
            problem = app.ProblemType.Value;
            [status, message] = app.model.setProblem(problem, geom);
            if ~status
              msgbox(message);
            else    
              app.updateGeomTab();
              message = sprintf('Created problem %s for geometry %s', ...
                  problem, geom);
            end
        end        

        function updateSupportsTab(app)
            regions = app.model.regionNames();
            app.SupportRegions.Items = regions;
            variableName = app.SupportVariable.Value;
            validbc = mp.BcFactory.validBc(variableName);
            regionName = app.SupportRegions.Value;
            app.SupportType.Items = string(validbc);
            if ~isempty(regionName)
              bcName = app.model.getBcName(regionName, variableName);
              app.SupportType.Value = bcName;
              bc = app.model.getBc(regionName, variableName);
              if ~isempty(bc) && ~app.defbcval.(char(bc.type)).enable
                app.BCValue.Value = app.defbcval.(char(bc.type)).val;
                app.BCValue.Enable = false;
              else
                app.BCValue.Enable = true;
                if ~isempty(bc) && bc ~= mp.BcType.NotSet
                   app.BCValue.Value = mat2str(bc.value);
                else
                  app.BCValue.Value = '';
                end
              end
            else
               app.BCValue.Value = ''; 
            end        
        end
                  
        function addBC(app)
           variable = app.SupportVariable.Value;
           region = app.SupportRegions.Value;
           bctype = app.SupportType.Value;
           value = app.BCValue.Value;
           val = strrep(value, 'Any', 'nan');
           val = strrep(val, 'any', 'nan');
           if isempty(val)
              params.value=[];
           else
              try
                params.value = eval(val);
              catch
                msg = sprintf('Failed to evaluete expression: %s', val);  
                app.displayMessage(msg);
                msgbox(msg);
                return
              end    
           end   
           [status, msg, ~] = app.model.setBc(region, variable, bctype, params);
           if status
             msg = sprintf('Added boundary condition for %s on %s',variable, region);
             app.displayMessage(msg);
           else
             app.diplayMessage('Failed to add boundary condition');
           end
        end
        
        function options = getMeshingOptions(app)
           options.lc = app.GloballcEditField.Value;
           options.order = app.MappingorderSpinner.Value;
           options.incomplete = app.IncompleteCheckBox.Value;
           if strcmp(app.ElementshapeDropDown.Value, "Quad")
             options.quadsonly = true;
           end
           options.transfinite = app.TransfiniteCheckBox.Value;
           options.transres = [app.XtransfiniteDensity.Value,
                               app.YtransfiniteDensity.Value,
                               app.ZtransfiniteDensity.Value];
        end
        
        function redrawMesh(app)
           cla(app.UIAxes, 'reset');
           options = struct();
           options.dim = 2;
           if app.Deformed.Value
              options.Displacement = app.model.getNodalDisplacement();
              if isempty(options.Displacement)
                  options = rmfield(options, 'Displacement');
              end
           end
           if app.model.hasMesh() 
             app.viewer.show(app.model.getMesh(), options);          
             app.UIAxes.XLimMode = 'auto';
             app.UIAxes.YLimMode = 'auto';
             view(app.UIAxes, 0, 90);
           end
        end
        
        function setupDefBcVal(app)
           app.defbcval.Fixity =       struct('val','[0,0,0]', 'enable', false);
           app.defbcval.FixityX =      struct('val','[0,Any,Any]', 'enable', false);
           app.defbcval.FixityY =      struct('val','[Any,0,Any]', 'enable',false);
           app.defbcval.FixityZ =      struct('val','[Any,Any,0]', 'enable',false);
           app.defbcval.FixityXY =     struct('val','[0,0,Any]', 'enable', false);
           app.defbcval.FixityXZ =     struct('val','[0,Any,0]', 'enable', false);
           app.defbcval.FixityYZ =     struct('val','[Any,0,0]', 'enable', false);
           app.defbcval.Displacement = struct('val','[1]', 'enable', true);
           app.defbcval.NotSet = struct('val', 'None', 'enable', false);
           app.defbcval.Traction = struct('val', '[1]', 'enable', true);
           app.defbcval.Pressure = struct('val', '0', 'enable', true);
           app.defbcval.Force = struct('val', '[0,0,0]', 'enable', true');
        end
        
        function addMaterialToList(app, materialName)
            msg = sprintf('Selected material: %s', materialName);
            app.displayMessage(msg);
        end
        
        function setCurrentMaterial(app, materialName)
            msg = sprintf('Selecting material as current')
            app.MatName.Value = materialName;
            app.displayMessage(msg);
        end
        
    end

    methods (Access = public)


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setupDefBcVal();
            mp.ProblemFactory.kernel('nadamak');
            app.populateProblemType();
            app.populateGeomType();
            app.model = SofModel();
            app.progress = mp.ProgressGUI();
            app.viewer =  mp.Viewer(app.UIAxes);
            app.updateGeomTab();
            app.updateSupportsTab(); 
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [file,path] = uigetfile('*.sof');
            if isequal(file,0)
                app.displayMessage('Openning file canceled');
            else
                [status, msg] = app.model.readConfig(fullfile(path, file));
                if status
                    app.projDir = path;
                    app.projFile = file;
                end
                app.displayMessage(msg);
            end
        end

        % Value changed function: GeomType
        function GeomTypeValueChanged(app, event)
            value = app.GeomType.Value;
            selectGeomTemplate(app, value);
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            [status, msg] = app.createNewProblem();
            if status
              app.displayMessage(msg);
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            options = struct();
            options.MeshingOptions = app.getMeshingOptions();
            tstart = tic;
            app.progress.setBar(waitbar(0, 'Please wait...', ...
                'Name','Nadamak Calculations'));
            app.progress.addCallback('ala',@app.displayMessage);
                app.model.calculate(app.progress, options);
            telapsed = toc(tstart);
            app.progress.report(0.9, 'Finishing');
            pause(0.5);
            app.progress.closeBar();
            msg = sprintf('Calculations finished in %f[s]', telapsed);
            app.displayMessage(msg);
            app.redrawMesh();
        end

        % Close request function: Sofview
        function SofviewCloseRequest(app, event)
           disp('Bye from Sofview')
           delete(app)
        end

        % Value changed function: KerneltypeDropDown
        function KerneltypeDropDownValueChanged(app, event)
            value = app.KerneltypeDropDown.Value;
            mp.ProblemFactory.kernel(value);
            msg = sprintf('Kernel set to %s', value);
            app.displayMessage(msg);
        end

        % Button pushed function: GenerateMesh
        function GenerateMeshPushed(app, event)
          meshingOptions = app.getMeshingOptions();
          app.displayMessage('Mesh generation started, please wait');
          [status, msg] = app.model.generateMesh(meshingOptions);
          if ~status
            msgbox(msg);
          else
            app.displayMessage(msg);
            app.displayMessage(app.model.meshInfo());
            app.redrawMesh();
          end         
        end

        % Value changed function: NodesCheckBox
        function NodesCheckBoxValueChanged(app, event)
            value = app.NodesCheckBox.Value;
            app.viewer.turnNodes(value);
        end

        % Value changed function: E1D
        function E1DValueChanged(app, event)
            value = app.E1D.Value;
            app.viewer.turnEdges(value);
        end

        % Value changed function: E2D
        function E2DValueChanged(app, event)
            value = app.E2D.Value;
            app.viewer.turnFaces(value);
        end

        % Menu selected function: PanMenu
        function PanMenuSelected(app, event)
           zoom(app.UIAxes, 'off');
           pan(app.UIAxes, 'on'); 
        end

        % Menu selected function: ZoomMenu
        function ZoomMenuSelected(app, event)
            pan(app.UIAxes, 'off');
            zoom(app.UIAxes, 'on');
        end

        % Value changed function: SupportVariable
        function SupportVariableValueChanged(app, event)
            app.updateSupportsTab();
        end

        % Value changed function: SupportType
        function SupportTypeValueChanged(app, event)
           bctype = app.SupportType.Value;
           %app.updateSupportsTab();
           if ~app.defbcval.(bctype).enable
             app.BCValue.Value = app.defbcval.(bctype).val;
             app.BCValue.Enable = false;
           else
             app.BCValue.Enable = true;
           end
           %app.BCValueHint.Text = sprintf('Hint for %s', bctype); 
        end

        % Menu selected function: ListBCMenu
        function ListBCMenuSelected(app, event)
            fid = 1;
            app.model.writeBc(fid);
        end

        % Value changed function: SupportRegions
        function SupportRegionsValueChanged(app, event)
            app.updateSupportsTab();
        end

        % Menu selected function: SaveprojectMenu
        function SaveprojectMenuSelected(app, event)
            app.saveProject();        
        end

        % Value changed function: BCValue
        function BCValueValueChanged(app, event)
            value = app.BCValue.Value;       
        end

        % Button pushed function: BCAdd
        function BCAddButtonPushed(app, event)
            app.addBC();
        end

        % Menu selected function: ListDofsMenu
        function ListDofsMenuSelected(app, event)
            fid = 1;
            app.model.writeDofs(fid);
        end

        % Value changed function: Deformed
        function DeformedValueChanged(app, event)
            value = app.Deformed.Value;
            app.redrawMesh();
        end

        % Value changed function: ElementshapeDropDown
        function ElementshapeDropDownValueChanged(app, event)
            value = app.ElementshapeDropDown.Value;
            app.IncompleteCheckBox.Enable = strcmp(value, 'Quad');
        end

        % Menu selected function: NoneMenu
        function NoneViewModeSelected(app, event)
             zoom(app.UIAxes, 'off');
             pan(app.UIAxes, 'off');
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
           zoom(app.UIAxes, 'off');
           pan(app.UIAxes, 'off'); 
           rotate3d(app.UIAxes, 'off');
           %resetplotview(app.UIAxes,'InitializeCurrentView');
           set(app.UIAxes,'xlimMode', 'auto');
           set(app.UIAxes,'ylimMode', 'auto');
        end

        % Menu selected function: Rotate3DMenu
        function Rotate3DMenuSelected(app, event)
           rotate3d(app.UIAxes, 'on'); 
        end

        % Menu selected function: FlagSHypMenu
        function FlagSHypMenuSelected(app, event)
            app.export('FlagSHyp');
        end

        % Value changed function: TransfiniteCheckBox
        function TransfiniteCheckBoxValueChanged(app, event)
            value = app.TransfiniteCheckBox.Value;
            if value
              app.XtransfiniteDensity.Enable = true;
              app.YtransfiniteDensity.Enable = true;
              app.ZtransfiniteDensity.Enable = true;
            else
              app.XtransfiniteDensity.Enable = false;
              app.YtransfiniteDensity.Enable = false;
              app.ZtransfiniteDensity.Enable = false;
            end  
        end

        % Value changed function: MatRegion
        function MatRegionValueChanged(app, event)
            regionName = app.MatRegion.Value;
            matName= app.model.getRegionMaterial(regionName);
            app.setCurrentMaterial(matName);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Sofview = uifigure;
            app.Sofview.Name = 'Sofview';
            app.Sofview.CloseRequestFcn = createCallbackFcn(app, @SofviewCloseRequest, true);
            app.FileMenu = uimenu(app.Sofview);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectMenuSelected, true);
            app.SaveprojectMenu.Text = 'Save project';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Separator = 'on';
            app.ExportMenu.Text = 'Export';
            app.FlagSHypMenu = uimenu(app.ExportMenu);
            app.FlagSHypMenu.MenuSelectedFcn = createCallbackFcn(app, @FlagSHypMenuSelected, true);
            app.FlagSHypMenu.Text = 'FlagSHyp';
            app.ViewMenu = uimenu(app.Sofview);
            app.ViewMenu.Text = 'View';
            app.ModeMenu = uimenu(app.ViewMenu);
            app.ModeMenu.Text = 'Mode';
            app.ZoomMenu = uimenu(app.ModeMenu);
            app.ZoomMenu.MenuSelectedFcn = createCallbackFcn(app, @ZoomMenuSelected, true);
            app.ZoomMenu.Text = 'Zoom';
            app.PanMenu = uimenu(app.ModeMenu);
            app.PanMenu.MenuSelectedFcn = createCallbackFcn(app, @PanMenuSelected, true);
            app.PanMenu.Text = 'Pan';
            app.Rotate3DMenu = uimenu(app.ModeMenu);
            app.Rotate3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Rotate3DMenuSelected, true);
            app.Rotate3DMenu.Text = 'Rotate3D';
            app.NoneMenu = uimenu(app.ModeMenu);
            app.NoneMenu.MenuSelectedFcn = createCallbackFcn(app, @NoneViewModeSelected, true);
            app.NoneMenu.Text = 'None';
            app.ResetMenu = uimenu(app.ViewMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.HelpMenu = uimenu(app.Sofview);
            app.HelpMenu.Text = 'Help';
            app.DebugMenu = uimenu(app.Sofview);
            app.DebugMenu.Text = 'Debug';
            app.ListBCMenu = uimenu(app.DebugMenu);
            app.ListBCMenu.MenuSelectedFcn = createCallbackFcn(app, @ListBCMenuSelected, true);
            app.ListBCMenu.Text = 'List BC';
            app.ListDofsMenu = uimenu(app.DebugMenu);
            app.ListDofsMenu.MenuSelectedFcn = createCallbackFcn(app, @ListDofsMenuSelected, true);
            app.ListDofsMenu.Text = 'ListDofs';
            app.TabGroup = uitabgroup(app.Sofview);
            app.NewTab = uitab(app.TabGroup);
            app.NewTab.Title = 'New';
            app.GeometrytypeLabel = uilabel(app.NewTab);
            app.GeometrytypeLabel.Text = 'Geometry type';
            app.GeomType = uidropdown(app.NewTab);
            app.GeomType.Items = {'Square', 'Rectangle', 'L-Shape', 'SquareHole', 'None', 'Rectangle with interface'};
            app.GeomType.ValueChangedFcn = createCallbackFcn(app, @GeomTypeValueChanged, true);
            app.GeomType.Value = 'None';
            app.TextArea = uitextarea(app.NewTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Caution: creating project invalidates previous data.'};
            app.ProblemtypeDropDownLabel = uilabel(app.NewTab);
            app.ProblemtypeDropDownLabel.Text = 'Problem type';
            app.ProblemType = uidropdown(app.NewTab);
            app.ProblemType.Items = {};
            app.ProblemType.Value = {};
            app.CreateButton = uibutton(app.NewTab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.KerneltypeDropDownLabel = uilabel(app.NewTab);
            app.KerneltypeDropDownLabel.Text = 'Kernel type';
            app.KerneltypeDropDown = uidropdown(app.NewTab);
            app.KerneltypeDropDown.Items = {'calfem', 'nadamak'};
            app.KerneltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @KerneltypeDropDownValueChanged, true);
            app.KerneltypeDropDown.Value = 'nadamak';
            app.MaterialsTab = uitab(app.TabGroup);
            app.MaterialsTab.Title = 'Materials';
            app.RegionLabel = uilabel(app.MaterialsTab);
            app.RegionLabel.Text = 'Region';
            app.MatRegion = uidropdown(app.MaterialsTab);
            app.MatRegion.Items = {'Option 1'};
            app.MatRegion.ValueChangedFcn = createCallbackFcn(app, @MatRegionValueChanged, true);
            app.MaterialLabel = uilabel(app.MaterialsTab);
            app.MaterialLabel.Text = 'Material';
            app.MatName = uidropdown(app.MaterialsTab);
            app.MatName.Items = {'default', 'steel', 'Option 3', 'Option 4'};
            app.MatName.Value = 'default';
            app.MatProperties = uipanel(app.MaterialsTab);
            app.MatProperties.Title = 'Material properties';
            app.UITable = uitable(app.MatProperties);
            app.UITable.ColumnName = {'Value'; 'Column 2'};
            app.UITable.RowName = {};
            app.NewMaterial = uibutton(app.MaterialsTab, 'push');
            app.NewMaterial.Text = 'Add new material';
            app.GeomTab = uitab(app.TabGroup);
            app.GeomTab.Title = 'Geom';
            app.RegionsListBoxLabel = uilabel(app.GeomTab);
            app.RegionsListBoxLabel.Text = 'Regions';
            app.RegionsListBox = uilistbox(app.GeomTab);
            app.RegionsListBox.Items = {};
            app.RegionsListBox.Value = {};
            app.GeomDimInfo = uilabel(app.GeomTab);
            app.MeshTab = uitab(app.TabGroup);
            app.MeshTab.Title = 'Mesh';
            app.MeshdensityPanel = uipanel(app.MeshTab);
            app.MeshdensityPanel.Title = 'Mesh density';
            app.GloballcEditFieldLabel = uilabel(app.MeshdensityPanel);
            app.GloballcEditFieldLabel.Text = 'Global lc:';
            app.GloballcEditField = uieditfield(app.MeshdensityPanel, 'numeric');
            app.GloballcEditField.LowerLimitInclusive = 'off';
            app.GloballcEditField.Limits = [0 Inf];
            app.GloballcEditField.ValueDisplayFormat = '%6.3f';
            app.GloballcEditField.Value = 1;
            app.TransfiniteCheckBox = uicheckbox(app.MeshdensityPanel);
            app.TransfiniteCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransfiniteCheckBoxValueChanged, true);
            app.TransfiniteCheckBox.Text = 'Transfinite';
            app.XSpinnerLabel = uilabel(app.MeshdensityPanel);
            app.XSpinnerLabel.Enable = 'off';
            app.XSpinnerLabel.Text = 'X';
            app.XtransfiniteDensity = uispinner(app.MeshdensityPanel);
            app.XtransfiniteDensity.Limits = [2 Inf];
            app.XtransfiniteDensity.Enable = 'off';
            app.XtransfiniteDensity.Value = 2;
            app.YSpinnerLabel = uilabel(app.MeshdensityPanel);
            app.YSpinnerLabel.Enable = 'off';
            app.YSpinnerLabel.Text = 'Y';
            app.YtransfiniteDensity = uispinner(app.MeshdensityPanel);
            app.YtransfiniteDensity.Limits = [2 Inf];
            app.YtransfiniteDensity.Enable = 'off';
            app.YtransfiniteDensity.Value = 2;
            app.ZSpinnerLabel = uilabel(app.MeshdensityPanel);
            app.ZSpinnerLabel.Enable = 'off';
            app.ZSpinnerLabel.Text = 'Z';
            app.ZtransfiniteDensity = uispinner(app.MeshdensityPanel);
            app.ZtransfiniteDensity.Limits = [2 Inf];
            app.ZtransfiniteDensity.Enable = 'off';
            app.ZtransfiniteDensity.Value = 2;
            app.ElementshapeLabel = uilabel(app.MeshTab);
            app.ElementshapeLabel.Text = 'Element shape:';
            app.ElementshapeDropDown = uidropdown(app.MeshTab);
            app.ElementshapeDropDown.Items = {'Quad', 'Triangle'};
            app.ElementshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementshapeDropDownValueChanged, true);
            app.ElementshapeDropDown.Value = 'Quad';
            app.MappingorderLabel = uilabel(app.MeshTab);
            app.MappingorderLabel.Text = 'Mapping order:';
            app.MappingorderSpinner = uispinner(app.MeshTab);
            app.MappingorderSpinner.Limits = [1 2];
            app.MappingorderSpinner.Value = 1;
            app.GenerateMesh = uibutton(app.MeshTab, 'push');
            app.GenerateMesh.ButtonPushedFcn = createCallbackFcn(app, @GenerateMeshPushed, true);
            app.GenerateMesh.Text = 'Generate';
            app.IncompleteCheckBox = uicheckbox(app.MeshTab);
            app.IncompleteCheckBox.Text = 'Incomplete';
            app.ApproximationTab = uitab(app.TabGroup);
            app.ApproximationTab.Title = 'Approximation';
            app.VariablesListBoxLabel = uilabel(app.ApproximationTab);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.VariablesListBox = uilistbox(app.ApproximationTab);
            app.FEMdegreeSpinnerLabel = uilabel(app.ApproximationTab);
            app.FEMdegreeSpinnerLabel.Text = 'FEM degree';
            app.FEMdegreeSpinner = uispinner(app.ApproximationTab);
            app.BoundaryConditionsTab = uitab(app.TabGroup);
            app.BoundaryConditionsTab.Title = 'Boundary Conditions';
            app.VariableDropDownLabel = uilabel(app.BoundaryConditionsTab);
            app.VariableDropDownLabel.Text = 'Variable:';
            app.SupportVariable = uidropdown(app.BoundaryConditionsTab);
            app.SupportVariable.Items = {'Displacement', 'Temperature'};
            app.SupportVariable.ValueChangedFcn = createCallbackFcn(app, @SupportVariableValueChanged, true);
            app.SupportVariable.Value = 'Displacement';
            app.RegionListBoxLabel = uilabel(app.BoundaryConditionsTab);
            app.RegionListBoxLabel.Text = 'Region';
            app.SupportRegions = uilistbox(app.BoundaryConditionsTab);
            app.SupportRegions.ValueChangedFcn = createCallbackFcn(app, @SupportRegionsValueChanged, true);
            app.SupporttypeDropDownLabel = uilabel(app.BoundaryConditionsTab);
            app.SupporttypeDropDownLabel.Text = 'Support type';
            app.SupportType = uidropdown(app.BoundaryConditionsTab);
            app.SupportType.ValueChangedFcn = createCallbackFcn(app, @SupportTypeValueChanged, true);
            app.ValueEditFieldLabel = uilabel(app.BoundaryConditionsTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.BCValue = uieditfield(app.BoundaryConditionsTab, 'text');
            app.BCValue.ValueChangedFcn = createCallbackFcn(app, @BCValueValueChanged, true);
            app.BCValueHint = uilabel(app.BoundaryConditionsTab);
            app.BCValueHint.Text = 'Label2';
            app.BCAdd = uibutton(app.BoundaryConditionsTab, 'push');
            app.BCAdd.ButtonPushedFcn = createCallbackFcn(app, @BCAddButtonPushed, true);
            app.BCAdd.Text = 'Add';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.CalculateButton = uibutton(app.AnalysisTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Sofview);
            title(app.UIAxes, 'Title')
            app.Tree = uitree(app.Sofview);
            app.ModelNode = uitreenode(app.Tree);
            app.ModelNode.Text = 'Model';
            app.GeometryNode = uitreenode(app.ModelNode);
            app.GeometryNode.Text = 'Geometry';
            app.SupportsNode = uitreenode(app.ModelNode);
            app.SupportsNode.Text = 'Supports';
            app.LoadsNode = uitreenode(app.ModelNode);
            app.LoadsNode.Text = 'Loads';
            app.StatusmessageLabel = uilabel(app.Sofview);
            app.StatusmessageLabel.Text = 'Status message:';
            app.StatusInfo = uilistbox(app.Sofview);
            app.StatusInfo.Items = {};
            app.StatusInfo.Value = {};
            app.Panel = uipanel(app.Sofview);
            app.NodesCheckBox = uicheckbox(app.Panel);
            app.NodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NodesCheckBoxValueChanged, true);
            app.NodesCheckBox.Text = 'Nodes';
            app.NodesCheckBox.Value = true;
            app.ElementsLabel = uilabel(app.Panel);
            app.ElementsLabel.Text = 'Elements';
            app.E1D = uicheckbox(app.Panel);
            app.E1D.ValueChangedFcn = createCallbackFcn(app, @E1DValueChanged, true);
            app.E1D.Text = '1D';
            app.E1D.Value = true;
            app.E2D = uicheckbox(app.Panel);
            app.E2D.ValueChangedFcn = createCallbackFcn(app, @E2DValueChanged, true);
            app.E2D.Text = '2D';
            app.E2D.Value = true;
            app.Deformed = uicheckbox(app.Sofview);
            app.Deformed.ValueChangedFcn = createCallbackFcn(app, @DeformedValueChanged, true);
            app.Deformed.Text = 'Deformed';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sofview

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Sofview)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Sofview)
        end
    end
end